classdef PiAvatarAppBasic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IPAddressEditFieldLabel  matlab.ui.control.Label
        IPAddressEditField       matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
        CopyrightLabel           matlab.ui.control.Label
        DisconnectButton         matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        piAvatar = []; % PiAvatar参照
        piState  = 'No connection'; % 状態初期化
        command  = 'Neutral'; % コマンド初期化
    end
    

    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            ipAddress    = app.IPAddressEditField.Value;
            app.piAvatar = PiAvatarBasic('IpAddress',ipAddress);
            app.piState  = 'Ready';
            app.ConnectButton.Enable    = 'off';
            app.DisconnectButton.Enable = 'on';
            app.StartButton.Enable      = 'on';
            % 画像の更新
            for iter = 1:5 % カメラ設定を反映するためのアイドリング
                app.piAvatar.step('Snapshot')
            end
            imshow(app.piAvatar.img,'Pararent',app.UIAxes)
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.piAvatar = [];
            app.piState = 'No connection';
            app.DisconnectButton.Enable = 'off';
            app.ConnectButton.Enable    = 'on';
            app.StartButton.Enable      = 'off';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Start ボタンのコールバック関数
            app.piState = 'In process';
            app.StartButton.Enable       = 'off';
            app.StopButton.Enable        = 'on';
            app.DisconnectButton.Enable  = 'off';
            % PiAvatarの制御
            precommand = 'Neutral';
            app.piAvatar.step('Neutral')
            while(strcmp(app.piState,'In process'))
                curcommand = app.command;
                if strcmp(curcommand,precommand)
                    app.piAvatar.step('Snapshot')
                else
                    app.piAvatar.step(curcommand)
                    precommand = curcommand;
                end
                % 表示画像の更新
                img_ = app.piAvatar.img;
                app.UIAxes.Children.CData = img_;
            end
        end
        
        function pushbuttonStop_Callback(hObject, ~, handles)
            % Stop ボタンのコールバック関数
            app.piState = 'Ready';
            app.command = 'Neutral';
            app.StopButton.Enable       = 'off';
            app.StartButton.Enable      = 'on';
            app.DisconnectButton.Enable = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            handles.piState = 'Ready';
            handles.command = 'Neutral';
            handles.StopButton.Enable       = 'off';
            handles.StartButton.Enable      = 'on';
            handles.DisconnectButton.Enable = 'on';
            guidata(hObject,handles); % GUIオブジェクトの更新
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.IPAddressEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddressEditField.Value = '192.168.0.10';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PiAvater(Basic)')
            app.CopyrightLabel = uilabel(app.UIFigure);
            app.CopyrightLabel.Text = 'Copyright (c) 2016-2017, Shogo MURAMATSU, All rights reserved.';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'STOP';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'START';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PiAvatarAppBasic

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end